classdef first_ex < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        BackLeftWheelSwitch        matlab.ui.control.Switch
        BackLeftWheelSwitchLabel   matlab.ui.control.Label
        BackRightWheelSwitch       matlab.ui.control.Switch
        BackRightWheelSwitchLabel  matlab.ui.control.Label
        LeftWheelSwitch            matlab.ui.control.Switch
        LeftWheelSwitchLabel       matlab.ui.control.Label
        RightWheelSwitch           matlab.ui.control.Switch
        RightWheelSwitchLabel      matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        clientID % The variable to manage the Coppellia socket client
        rightwheel % right_wheel front handler
        leftwheel  % left wheel front handler
        sensors
        first_sensors_call
        map 
        logic_map
        vrep       %  variable for the api
        to_do
    end
    
    
    methods (Access = private)


    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function connectWithCopellia(app)
             close all
             clc
             %using the prototype file (remoteApiProto.m)
             app.to_do = true;
             app.vrep=remApi('remoteApi'); 
             app.vrep.simxFinish(-1);  % ended all the pended connection
             app.clientID =app.vrep.simxStart('127.0.0.1',19999,true,true,5000,5);
             app.map = app.UIAxes(1,1);
             %disp('inizio')
             app.first_sensors_call = true;
             errors_config=[];
             app.logic_map = zeros(10,5);
             app.logic_map = app.logic_map+1/2;
             
             if app.clientID>-1
                   msgbox ("Client connected..");
                  [gestore,app.rightwheel]=app.vrep.simxGetObjectHandle(app.clientID,'wheel_right',app.vrep.simx_opmode_blocking);
                  [gestore,app.leftwheel]=app.vrep.simxGetObjectHandle(app.clientID,'wheel_left',app.vrep.simx_opmode_blocking);
                  [gestore,app.sensors]=app.vrep.simxGetObjectHandle(app.clientID,'laser',app.vrep.simx_opmode_blocking);
              
                  for i=1: length(errors_config)
                     if errors_config(i)~= 0
                         msgbox('I cannot communicate properly with Coppelia due to an handler error');
                         break
                     end
                   end

            else
                  msgbox("No response from the socket interface..");
            end
         
        end

        % Button down function: UIAxes
        function map_update(app, event)
             disp("ok")
        end

        % Value changed function: BackLeftWheelSwitch
        function back(app, event)
          state = event.Value; 
          if app.first_sensors_call== true
             [result_sensors,~,err] = app.vrep.simxReadProximitySensor(app.clientID,app.sensors,app.vrep.simx_opmode_streaming);
             disp(result_sensors);
             app.first_sensors_call=false;
          else
              [e1,d,C] = app.vrep.simxReadProximitySensor(app.clientID,app.sensors,app.vrep.simx_opmode_streaming);
              error_velocity= app.vrep.simxSetJointTargetVelocity(app.clientID,app.leftwheel,0.2,app.vrep.simx_opmode_blocking);
              error_velocity= app.vrep.simxSetJointTargetVelocity(app.clientID,app.rightwheel,0.2,app.vrep.simx_opmode_blocking);
              [e1,d,C] = app.vrep.simxReadProximitySensor(app.clientID,app.sensors,app.vrep.simx_opmode_streaming);
            
          end 

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightWheelSwitchLabel = uilabel(app.LeftPanel);
            app.RightWheelSwitchLabel.Text = 'Right Wheel';
            app.RightWheelSwitch = uiswitch(app.LeftPanel, 'slider');
            app.LeftWheelSwitchLabel = uilabel(app.LeftPanel);
            app.LeftWheelSwitchLabel.Text = 'Left Wheel';
            app.LeftWheelSwitch = uiswitch(app.LeftPanel, 'slider');
            app.BackRightWheelSwitchLabel = uilabel(app.LeftPanel);
            app.BackRightWheelSwitchLabel.Text = 'Back Right Wheel';
            app.BackRightWheelSwitch = uiswitch(app.LeftPanel, 'slider');
            app.BackLeftWheelSwitchLabel = uilabel(app.LeftPanel);
            app.BackLeftWheelSwitchLabel.Text = 'Back Left Wheel';
            app.BackLeftWheelSwitch = uiswitch(app.LeftPanel, 'slider');
            app.BackLeftWheelSwitch.ValueChangedFcn = createCallbackFcn(app, @back, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Occupation GRID')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @map_update, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = first_ex

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @connectWithCopellia)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end